classdef youle_20241020 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Image            matlab.ui.control.Image
        SegmentButton    matlab.ui.control.Button
        LoadImageButton  matlab.ui.control.Button
        ProcessedAxes    matlab.ui.control.UIAxes
        OriginalAxes     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            if isequal(file, 0)
                return; % 用户取消选择
            end
            app.Image = imread(fullfile(path, file));
            imshow(app.Image, 'Parent', app.OriginalAxes);
            title(app.OriginalAxes, '原始图像');
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            if isempty(app.Image)
        uialert(app.UIFigure, '请先加载图像！', '错误');
        return;
    end
    
    % 检查图像是否为 RGB 图像
    if size(app.Image, 3) == 3
        % 转换为灰度图像
        grayImage = rgb2gray(app.Image);
    elseif size(app.Image, 3) == 1
        % 如果已经是灰度图像，则直接使用
        grayImage = app.Image;
    else
        % 处理其他类型的图像
        uialert(app.UIFigure, '不支持的图像格式！', '错误');
        return;
    end
    
    % 使用 Otsu 方法计算最佳阈值
    level = graythresh(grayImage);
    binaryImage = imbinarize(grayImage, level);
    
    % 显示处理后的图像
    imshow(binaryImage, 'Parent', app.ProcessedAxes);
    title(app.ProcessedAxes, '分割结果');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalAxes = uiaxes(app.UIFigure);
            title(app.OriginalAxes, 'OriginalAxes')
            xlabel(app.OriginalAxes, 'X')
            ylabel(app.OriginalAxes, 'Y')
            zlabel(app.OriginalAxes, 'Z')
            app.ProcessedAxes = uiaxes(app.UIFigure);
            title(app.ProcessedAxes, 'ProcessedAxes')
            xlabel(app.ProcessedAxes, 'X')
            ylabel(app.ProcessedAxes, 'Y')
            zlabel(app.ProcessedAxes, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'LoadImageButton';
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'SegmentButton';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = youle_20241020

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end